classdef DAQ_Recording_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotTabs                 matlab.ui.container.TabGroup
        EMGTab                   matlab.ui.container.Tab
        AnalogTab                matlab.ui.container.Tab
        ConfigPanel              matlab.ui.container.Panel
        EnableDragonflyCheckbox  matlab.ui.control.CheckBox
        FileStringField          matlab.ui.control.EditField
        PathLabel                matlab.ui.control.Label
        SelectPathButton         matlab.ui.control.Button
        SampleRateField          matlab.ui.control.NumericEditField
        SampleRateFieldLabel     matlab.ui.control.Label
        TrialNumberFieldLabel    matlab.ui.control.Label
        TrialNumberField         matlab.ui.control.NumericEditField
        FileStringFieldLabel     matlab.ui.control.Label
        SubjectIDField           matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        SubjectIDFieldLabel      matlab.ui.control.Label
        ControlPanel             matlab.ui.container.Panel
        ReconnectButton          matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        StatusPanel              matlab.ui.container.Panel
        RecordingStatusLabel     matlab.ui.control.Label
        ElapsedTimeLabel         matlab.ui.control.Label
        DAQStatusLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        dq                         % DAQ object
        dqout                      % DAQ output object
        Fs                         % Sampling frequency
        StartbuttonState           % Start button state
        StopbuttonState            % Stop button state
        DF                         % Dragonfly struct
        Num_ch_AI                  % Number of analog input channels
        SubID                      % Subject ID
        server                     % Message manager server
        fid_write                  % File ID for writing
        Filename                   % Current filename
        pathname                   % Save path
        OPfile                     % Output file path
        Filestr                    % File string
        TrlNum                     % Trial number
        EMG_CH                     % EMG channel numbers
        meta_data                  % Metadata struct
        meta_path                  % Metadata path
        metadata_filename          % Metadata filename
        OPfile_METADATA            % Metadata file path
        muscle_labels              % Muscle labels
        analog_input_labels        % Analog input labels
        analog_in_ch               % Analog input channels
        fig_path                   % Figure save path
        muscle_names               % Muscle names
        timer_obj                  % Timer for updating elapsed time
        start_time                 % Recording start time
        EMGAxes                    % Axes for EMG plots
        AnalogAxes                 % Axes for analog plots
        EMGLines                   % Line objects for EMG plots
        AnalogLines                % Line objects for analog plots
        plot_buffer_size           % Size of plot buffer
        time_buffer                % Time buffer for plotting
        emg_buffer                 % EMG data buffer for plotting
        analog_buffer              % Analog data buffer for plotting
        dragonfly_connected        % Flag for Dragonfly connection status
        VOP_baseDir                % Dragonfly base directory
    end
    
    methods (Access = private)
        
        function muscle_names = get_muscle_names(app, muscle_labels)
            muscle_names = cell(size(muscle_labels));
            for i = 1:length(muscle_labels)
                label = muscle_labels{i};
                if strcmp(label, 'NC')
                    muscle_names{i} = 'NC';
                else

                    label = regexprep(label, '^[LR][-_]?', '');
                    muscle_names{i} = label;
                end
            end
        end
        
        function nextNum = getNextTrialNumber(app)
            if isempty(app.pathname) || ~exist(app.pathname, 'dir')
                nextNum = 1;
                return;
            end
            
            files = dir(fullfile(app.pathname, '*.bin'));
            if isempty(files)
                nextNum = 1;
            else
                nums = [];
                for i = 1:length(files)
                    tokens = regexp(files(i).name, 'Trl(\d+)\.bin', 'tokens');
                    if ~isempty(tokens)
                        nums(end+1) = str2double(tokens{1}{1});
                    end
                end
                if isempty(nums)
                    nextNum = 1;
                else
                    nextNum = max(nums) + 1;
                end
            end
        end
        
        function initializePlots(app)
            try
                delete(allchild(app.EMGTab));
                delete(allchild(app.AnalogTab));
                
                numEMG = min(length(app.EMG_CH), length(app.muscle_labels));
                rows_emg = ceil(numEMG/2);
                
                % Create EMG plots
                app.EMGAxes = gobjects(numEMG, 1);
                app.EMGLines = gobjects(numEMG, 1);
                
                fprintf('Creating %d EMG plots in %d rows\n', numEMG, rows_emg);
                
                for i = 1:numEMG
                    try
                        % Set plot position
                        col = mod(i-1, 2) + 1;
                        row = ceil(i/2);
                        
                        % Define vertical margin and the gap size between plots
                        v_margin = 0.1;  % Reserve 10% of total height for top/bottom margins
                        gap_size = 0.2 / rows_emg; % Make the gap smaller as rows increase
                
                        % Calculate the height of each plot proportionally
                        total_plot_space = 1 - v_margin;
                        plot_height = (total_plot_space / rows_emg) - gap_size;
                        
                        % Calculate the 'bottom' position for the current row, starting from the top
                        plot_bottom = 1 - (v_margin / 2) - (row * (plot_height + gap_size)) + gap_size;
                
                        left = 0.05 + (col-1) * 0.5;
                        width = 0.4;
                        
                        app.EMGAxes(i) = axes('Parent', app.EMGTab, ...
                            'Position', [left, plot_bottom, width, plot_height]);
                        
                        % Create plot line
                        app.EMGLines(i) = plot(app.EMGAxes(i), [0 1], [0 0], 'b-', 'LineWidth', 1);
                        
                        app.EMGAxes(i).Color = [0.94 0.94 0.94];
                        app.EMGAxes(i).XColor = 'k';
                        app.EMGAxes(i).YColor = 'k';
                        app.EMGAxes(i).FontSize = 8;
                        
                        grid(app.EMGAxes(i), 'on');
                        ylim(app.EMGAxes(i), [-0.01 0.01]);
                        xlim(app.EMGAxes(i), [0 2]);
                        
                        % Add title
                        title(app.EMGAxes(i), sprintf('%d-%s', i, app.muscle_labels{i}), ...
                            'Color', 'k', 'FontSize', 8);

                        current_row = ceil(i / 2);

                        % If the plot is NOT in the last row, hide the x-axis tick labels
                        if current_row < rows_emg
                            app.EMGAxes(i).XTickLabel = [];
                        else
                            xlabel(app.EMGAxes(i), 'Time (s)', 'Color', 'k');
                        end
                        
                        fprintf('Created EMG plot %d: %s\n', i, app.muscle_labels{i});
                        
                    catch ME_emg
                        fprintf('Failed to create EMG plot %d: %s\n', i, ME_emg.message);
                    end
                end
                
                % Create analog plots
                numAnalog = length(app.analog_in_ch);
                rows_analog = ceil(numAnalog/2);
                
                app.AnalogAxes = gobjects(numAnalog, 1);
                app.AnalogLines = gobjects(numAnalog, 1);
                
                fprintf('Creating %d Analog plots in %d rows\n', numAnalog, rows_analog);
                
                for i = 1:numAnalog
                    try
                        col = mod(i-1, 2) + 1;
                        row = ceil(i/2);
                        
                        % Define vertical margin and the gap size between plots
                        v_margin = 0.1;
                        gap_size = 0.2 / rows_analog;
                
                        % Calculate the height of each plot proportionally
                        total_plot_space = 1 - v_margin;
                        plot_height = (total_plot_space / rows_analog) - gap_size;
                        
                        % Calculate the 'bottom' position for the current row, starting from the top
                        plot_bottom = 1 - (v_margin / 2) - (row * (plot_height + gap_size)) + gap_size;
                
                        left = 0.05 + (col-1) * 0.5;
                        width = 0.4;
                        
                        app.AnalogAxes(i) = axes('Parent', app.AnalogTab, ...
                            'Position', [left, plot_bottom, width, plot_height]);
                        
                        app.AnalogLines(i) = plot(app.AnalogAxes(i), [0 1], [0 0], 'b-', 'LineWidth', 1);
                        
                        app.AnalogAxes(i).Color = [0.94 0.94 0.94];
                        app.AnalogAxes(i).XColor = 'k';
                        app.AnalogAxes(i).YColor = 'k';
                        app.AnalogAxes(i).FontSize = 8;
                        
                        grid(app.AnalogAxes(i), 'on');
                        ylim(app.AnalogAxes(i), [-1 6]);
                        xlim(app.AnalogAxes(i), [0 2]);
                        
                        title(app.AnalogAxes(i), sprintf('AI-%d: %s', i-1, app.analog_input_labels{i}), ...
                            'Color', 'k', 'FontSize', 7);

                        current_row = ceil(i / 2);

                        % If the plot is NOT in the last row, hide the x-axis tick labels
                        if current_row < rows_analog
                            app.AnalogAxes(i).XTickLabel = [];
                        else
                            xlabel(app.AnalogAxes(i), 'Time (s)', 'Color', 'k');
                        end
                        
                        fprintf('Created Analog plot %d: %s\n', i, app.analog_input_labels{i});
                        
                    catch ME_analog
                        fprintf('Failed to create Analog plot %d: %s\n', i, ME_analog.message);
                    end
                end
                
                app.time_buffer = zeros(app.plot_buffer_size, 1);
                app.emg_buffer = zeros(app.plot_buffer_size, numEMG);
                app.analog_buffer = zeros(app.plot_buffer_size, numAnalog);
                
                drawnow;
                
                fprintf('Plot initialization complete!\n');
                fprintf('EMG plots: %d created\n', sum(isvalid(app.EMGAxes)));
                fprintf('Analog plots: %d created\n', sum(isvalid(app.AnalogAxes)));
                
            catch ME
                warning('DAQ:PlotInit', 'Failed to initialize plots: %s', ME.message);
                fprintf('Plot initialization error: %s\n', ME.message);
                fprintf('Falling back to simple plot layout...\n');
                app.initializePlotsSimple();
            end
        end
        
        % Connect to DAQ
        function refreshDAQ(app)
            try
                % Clear existing connections
                delete(instrfind);
                
                % Reset DAQ system
                daqreset;
                
                % Find available DAQ devices
                d = daqlist("ni");
                
                if isempty(d)
                    app.DAQStatusLabel.Text = sprintf('No DAQ Available\nLast Trial: %d, Next Trial: %d', ...
                        app.TrlNum-1, app.TrlNum);
                    app.DAQStatusLabel.FontColor = 'red';
                    return;
                end
                
                % Set up DAQ
                app.dq = daq("ni");
                app.dq.Rate = app.Fs;
                
                % Configure input channels
                ANA_IN_params = addinput(app.dq, "Dev1", 0:15, "Voltage");
                ANA_IN_EMG = addinput(app.dq, "Dev1", 16:79, "Voltage");
                app.Num_ch_AI = length(ANA_IN_params) + length(ANA_IN_EMG);
                
                % Set terminal configuration
                for i = 1:length(ANA_IN_params)
                    ANA_IN_params(i).TerminalConfig = 'SingleEndedNonReferenced';
                end
                for i = 1:length(ANA_IN_EMG)
                    ANA_IN_EMG(i).TerminalConfig = 'SingleEnded';
                end
                
                % Set up digital outputs
                try
                    app.dqout = daq("ni");
                    addoutput(app.dqout, "Dev1", "Port1/Line1", "Digital");
                    addoutput(app.dqout, "Dev1", "Port1/Line2", "Digital");
                catch
                    warning('DAQ:DigitalOutput', 'Digital output not available');
                    app.dqout = [];
                end
                
                app.DAQStatusLabel.Text = sprintf('DAQ Connected\nLast Trial: %d, Next Trial: %d', ...
                    app.TrlNum-1, app.TrlNum);
                app.DAQStatusLabel.FontColor = 'green';
                
                % Save channel count
                app.meta_data.total_analog_in_ch = app.Num_ch_AI;
                
                % Connect to Dragonfly
                if isprop(app, 'EnableDragonflyCheckbox') && app.EnableDragonflyCheckbox.Value
                    app.connectToDragonfly();
                end
                
            catch ME
                app.DAQStatusLabel.Text = 'DAQ Connection Failed';
                app.DAQStatusLabel.FontColor = 'red';
                uialert(app.UIFigure, ME.message, 'DAQ Error');
                fprintf('DAQ Error: %s\n', ME.message);
            end
        end
        
        % Connect to Dragonfly
        function connectToDragonfly(app)
            app.dragonfly_connected = false;
            
            if ~app.EnableDragonflyCheckbox.Value
                return;
            end
            
            try
                % Disconnect if already connected
                try
                    DisconnectFromMMM();
                catch

                end
                
                % Set message types
                MessageTypes = {'SCS_REQUEST'};
                
                % Connect to message manager
                ConnectToMMM(0, app.VOP_baseDir, ...
                    fullfile(app.VOP_baseDir, 'SCS_SMA_message_defs.mat'), ...
                    ['-server_name ' app.server]);
                
                % Subscribe to messages
                Subscribe(MessageTypes{:});
                
                % Set up DF structure
                if ~isfield(app, 'DF') || isempty(app.DF)
                    app.DF = struct();
                    app.DF.defines = struct();
                    app.DF.MDF = struct();
                end
                
                % Configure DF settings
                app.DF.defines.SCS_EMG_FS = app.Fs;
                app.DF.defines.SCS_MAX_EMG_CHANS = length(app.EMG_CH);
                
                % Load message definitions
                try
                    msg_def_file = fullfile(app.VOP_baseDir, 'SCS_SMA_message_defs.mat');
                    if exist(msg_def_file, 'file')
                        msg_defs = load(msg_def_file);
                        if isfield(msg_defs, 'MDF')
                            app.DF.MDF = msg_defs.MDF;
                        else
                            warning('DAQ:MessageDefs', 'MDF structure not found in message definitions');
                            app.DF.MDF.SCS_EMG_DATA_DIFF = struct();
                        end
                    else
                        warning('DAQ:MessageDefsFile', 'Message definitions file not found: %s', msg_def_file);
                        app.DF.MDF.SCS_EMG_DATA_DIFF = struct();
                    end
                catch ME
                    warning('DAQ:MessageDefsLoad', 'Could not load message definitions: %s', ME.message);
                    app.DF.MDF.SCS_EMG_DATA_DIFF = struct();
                end
                
                app.dragonfly_connected = true;
                fprintf('Connected to Dragonfly Message Manager\n');
                
            catch ME
                app.dragonfly_connected = false;
                warning('DAQ:DragonflyConnection', 'Could not connect to Dragonfly Message Manager: %s', ME.message);
                fprintf('Dragonfly connection failed: %s\n', ME.message);
            end
        end
        
        function logAndBroadcastData(app, src, ~)
            try
                % Read data from DAQ
                [data, timestamps, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
                
                % Write data to file
                if ~isempty(app.fid_write) && app.fid_write > 0
                    try
                        fwrite(app.fid_write, [timestamps, data]', 'double');
                    catch ME_file
                        % File handle invalid
                        warning('DAQ:FileWrite', 'File write failed (recording may have stopped): %s', ME_file.message);
                        app.fid_write = -1;
                        return;
                    end
                else
                    % No valid file handle
                    return;
                end
                
                % Update plots
                app.updatePlots(data, timestamps);
                
                % Send data to Dragonfly
                if app.dragonfly_connected && isprop(app, 'EnableDragonflyCheckbox') && app.EnableDragonflyCheckbox.Value
                    try
                        % Select channels for Dragonfly
                        analog_indices = 1:16;
                        emg_indices = app.EMG_CH;
                        
                        % Check data size
                        max_idx = size(data, 2);
                        valid_analog = analog_indices(analog_indices <= max_idx);
                        valid_emg = emg_indices(emg_indices <= max_idx);
                        
                        % Combine data
                        selected_data = data(:, [valid_analog, valid_emg]);
                        data_1d = selected_data(:);
                        
                        % Send message if structure is valid
                        if ~isempty(data_1d) && isfield(app.DF, 'MDF') && isfield(app.DF.MDF, 'SCS_EMG_DATA_DIFF')
                            % Send message
                            app.DF.defines.SCS_EMG_SAMPLES_PER_MSG = length(timestamps);
                            app.DF.defines.TrialNumber = app.TrlNum;
                            
                            msg = app.DF.MDF.SCS_EMG_DATA_DIFF;
                            msg.source_timestamp = timestamps';
                            msg.data = data_1d';
                            
                            SendMessage('SCS_EMG_DATA_DIFF', msg);
                        end
                        
                    catch ME_df
                        % Limit warning frequency
                        persistent last_dragonfly_warning_time;
                        current_time = now;
                        
                        if isempty(last_dragonfly_warning_time) || (current_time - last_dragonfly_warning_time) > 10/86400
                            warning('DAQ:DragonflyFailed', 'Dragonfly broadcast failed: %s', ME_df.message);
                            last_dragonfly_warning_time = current_time;
                        end
                    end
                end
                
            catch ME
                % Handle file errors
                if contains(ME.message, 'file identifier') || contains(ME.message, 'Invalid file')
                    warning('DAQ:AcquisitionError', 'File error in data acquisition (recording may have stopped): %s', ME.message);
                    app.fid_write = -1;
                else
                    warning('DAQ:AcquisitionError', 'Error in data acquisition: %s', ME.message);
                    % Stop on hardware errors
                    if contains(ME.message, 'hardware') || contains(ME.message, 'device')
                        app.StopButtonPushed([]);
                    end
                end
            end
        end
        
        % Update plots
        function updatePlots(app, data, timestamps)
            try
                % Update data buffers
                n_new = length(timestamps);
                
                % Skip if no data or too much data
                if n_new == 0 || n_new >= app.plot_buffer_size
                    return;
                end
                
                % Shift buffer data
                app.time_buffer(1:end-n_new) = app.time_buffer(n_new+1:end);
                app.time_buffer(end-n_new+1:end) = timestamps;
                
                % Update EMG data
                max_idx = size(data, 2);
                valid_emg_idx = app.EMG_CH(app.EMG_CH <= max_idx);
                
                if ~isempty(valid_emg_idx) && length(valid_emg_idx) <= size(app.emg_buffer, 2)
                    emg_data = data(:, valid_emg_idx);
                    n_valid_emg = size(emg_data, 2);
                    
                    app.emg_buffer(1:end-n_new, 1:n_valid_emg) = app.emg_buffer(n_new+1:end, 1:n_valid_emg);
                    app.emg_buffer(end-n_new+1:end, 1:n_valid_emg) = emg_data;
                    
                    % Update EMG plots
                    for i = 1:min(length(app.EMGLines), n_valid_emg)
                        if isvalid(app.EMGLines(i)) && isvalid(app.EMGAxes(i))
                            set(app.EMGLines(i), 'XData', app.time_buffer, 'YData', app.emg_buffer(:, i));
                            % Auto-scale Y axis occasionally
                            if mod(n_new, 100) == 0  % Every 100 updates
                                data_range = [min(app.emg_buffer(:, i)), max(app.emg_buffer(:, i))];
                                if diff(data_range) > 0
                                    ylim(app.EMGAxes(i), data_range + [-0.1, 0.1] * diff(data_range));
                                end
                            end
                        end
                    end
                end
                
                % Update analog data
                valid_analog_idx = app.analog_in_ch(app.analog_in_ch <= max_idx);
                
                if ~isempty(valid_analog_idx) && length(valid_analog_idx) <= size(app.analog_buffer, 2)
                    analog_data = data(:, valid_analog_idx);
                    n_valid_analog = size(analog_data, 2);
                    
                    app.analog_buffer(1:end-n_new, 1:n_valid_analog) = app.analog_buffer(n_new+1:end, 1:n_valid_analog);
                    app.analog_buffer(end-n_new+1:end, 1:n_valid_analog) = analog_data;
                    
                    % Update analog plots
                    for i = 1:min(length(app.AnalogLines), n_valid_analog)
                        if isvalid(app.AnalogLines(i)) && isvalid(app.AnalogAxes(i))
                            set(app.AnalogLines(i), 'XData', app.time_buffer, 'YData', app.analog_buffer(:, i));
                            % Auto-scale Y axis
                            if mod(n_new, 100) == 0  % Every 100 updates
                                data_range = [min(app.analog_buffer(:, i)), max(app.analog_buffer(:, i))];
                                if diff(data_range) > 0
                                    ylim(app.AnalogAxes(i), data_range + [-0.1, 0.1] * diff(data_range));
                                end
                            end
                        end
                    end
                end
                
                % Limit display updates for performance
                persistent update_counter;
                if isempty(update_counter)
                    update_counter = 0;
                end
                update_counter = update_counter + 1;
                
                if mod(update_counter, 50) == 1 % Use remainder 1 to trigger on the first run
                    % Update EMG plots
                    for i = 1:min(length(app.EMGLines), n_valid_emg)
                        if isvalid(app.EMGLines(i)) && isvalid(app.EMGAxes(i))
                            data_range = [min(app.emg_buffer(:, i)), max(app.emg_buffer(:, i))];
                            % Add a small epsilon to avoid zero range
                            if diff(data_range) > 1e-6 
                                padding = 0.1 * diff(data_range);
                                ylim(app.EMGAxes(i), [data_range(1) - padding, data_range(2) + padding]);
                            end
                        end
                    end
                
                    % Update analog plots
                    for i = 1:min(length(app.AnalogLines), n_valid_analog)
                        if isvalid(app.AnalogLines(i)) && isvalid(app.AnalogAxes(i))
                            data_range = [min(app.analog_buffer(:, i)), max(app.analog_buffer(:, i))];
                            if diff(data_range) > 1e-6
                                 padding = 0.1 * diff(data_range);
                                 ylim(app.AnalogAxes(i), [data_range(1) - padding, data_range(2) + padding]);
                            end
                        end
                    end
                end
                
            catch ME
                warning('DAQ:PlotUpdate', 'Plot update failed: %s', ME.message);
            end
        end
        
        % Update timer display
        function updateElapsedTime(app)
            try
                elapsed = toc(app.start_time);
                app.ElapsedTimeLabel.Text = sprintf('Elapsed: %.1f s | Trial: %d', elapsed, app.TrlNum);
            catch
                % Handle timer errors
            end
        end
        
        % Save trial metadata
        function saveMetadata(app)
            try
                app.meta_data.sub_id = app.SubID;
                app.meta_data.date = date;
                app.meta_data.timestamp = datestr(now, 'HHMMSS');
                app.meta_data.exp_name = app.Filestr;
                app.meta_data.trl_num = app.TrlNum;
                app.meta_data.fs = app.Fs;
                app.meta_data.total_num_emg_ch = length(app.muscle_labels);
                app.meta_data.emg_ch_number = app.EMG_CH;
                app.meta_data.musc_labels = app.muscle_labels;
                app.meta_data.musc_names = app.muscle_names;
                app.meta_data.analog_input_labels = app.analog_input_labels;
                app.meta_data.analog_in_ch = app.analog_in_ch;
                app.meta_data.total_analog_in_ch = app.Num_ch_AI;
                
                % Additional metadata
                app.meta_data.updaterate = 0.1;
                app.meta_data.updaterate_unit = 'seconds';
                app.meta_data.stim = 0;
                app.meta_data.pulse_width = 200;
                app.meta_data.pulse_width_unit = 'micro_seconds';
                app.meta_data.duty_cycle = 100;
                app.meta_data.dutycycle_unit = 'percent';
                
                meta_data = app.meta_data;
                save(app.OPfile_METADATA, 'meta_data');
                
                fprintf('Metadata saved: %s\n', app.OPfile_METADATA);
                
            catch ME
                warning('DAQ:MetadataSave', 'Failed to save metadata: %s', ME.message);
            end
        end
        
        % Show saved data plots
        function displaySavedData(app)
            try
                if ~exist(app.OPfile, 'file')
                    warning('DAQ:FileNotFound', 'Data file not found: %s', app.OPfile);
                    return;
                end
                
                % Load data file
                fid_read = fopen(app.OPfile, 'r');
                if fid_read == -1
                    warning('DAQ:FileRead', 'Could not open file for reading: %s', app.OPfile);
                    return;
                end
                
                [dataread, ~] = fread(fid_read, [app.Num_ch_AI+1, inf], 'double');
                fclose(fid_read);
                
                if isempty(dataread)
                    warning('DAQ:NoData', 'No data read from file');
                    return;
                end
                
                % Create EMG plots
                fig_emg = figure('Name', sprintf('EMG Data - Trial %d', app.TrlNum-1));
                fig_emg.WindowState = 'maximized';
                set(fig_emg, 'Color', [0 0 0]);
                
                % Plot EMG channels
                for j = 1:length(app.EMG_CH)
                    ch_idx = app.EMG_CH(j);
                    if ch_idx+1 <= size(dataread, 1)
                        ax = subplot(8, 2, j);
                        plot(dataread(1,:), dataread(ch_idx+1,:), '-y', 'LineWidth', 1);
                        set(ax, 'Color', [.15 .15 .15], 'XColor', [0.9725 0.9725 0.9725], ...
                            'YColor', [0.9725 0.9725 0.9725]);
                        grid on;
                        ylim([-1 1]);
                        title(sprintf('%d-%s', j, app.muscle_labels{j}), 'Color', [0.9725 0.9725 0.9725]);
                    end
                end
                
                % Save EMG plots
                emg_fig_name = sprintf('EMG_Trial_%03d.png', app.TrlNum-1);
                saveas(fig_emg, fullfile(app.fig_path, emg_fig_name));
                
                % Create analog plots
                fig_analog = figure('Name', sprintf('Analog Inputs - Trial %d', app.TrlNum-1));
                fig_analog.WindowState = 'maximized';
                set(fig_analog, 'Color', [0 0 0]);
                
                % Plot analog channels
                for j = 1:length(app.analog_in_ch)
                    if j+1 <= size(dataread, 1)
                        ax = subplot(8, 2, j);
                        iData = dataread(j+1,:);
                        % Scale channel 14
                        if j == 14
                            iData = iData * 20/10;
                        end
                        plot(dataread(1,:), iData, '-y', 'LineWidth', 1);
                        set(ax, 'Color', [.15 .15 .15], 'XColor', [0.9725 0.9725 0.9725], ...
                            'YColor', [0.9725 0.9725 0.9725]);
                        grid on;
                        ylim([-1 6]);
                        title(sprintf('AI-%d: %s', j-1, app.analog_input_labels{j}), ...
                            'Color', [0.9725 0.9725 0.9725], 'FontSize', 8);
                    end
                end
                
                % Save analog plots
                analog_fig_name = sprintf('Analog_Trial_%03d.png', app.TrlNum-1);
                saveas(fig_analog, fullfile(app.fig_path, analog_fig_name));
                
                fprintf('Data visualization complete. Figures saved.\n');
                
            catch ME
                warning('DAQ:DisplayError', 'Error displaying saved data: %s', ME.message);
            end
        end

        function cleanup(app)
            try
                % Stop DAQ
                if ~isempty(app.dq) && isvalid(app.dq) && app.dq.Running
                    stop(app.dq);
                    pause(0.1);
                end
                
                % Stop timer
                if ~isempty(app.timer_obj) && isvalid(app.timer_obj)
                    stop(app.timer_obj);
                    delete(app.timer_obj);
                end
                
                % Close file if open
                if ~isempty(app.fid_write) && app.fid_write > 0
                    try
                        fclose(app.fid_write);
                        fprintf('File closed during cleanup\n');
                    catch ME_cleanup
                        % File already closed
                        if ~contains(ME_cleanup.message, 'Invalid file identifier')
                            warning('DAQ:Cleanup', 'File close error during cleanup: %s', ME_cleanup.message);
                        end
                    end
                    app.fid_write = -1;
                end
                
                % Disconnect from Dragonfly
                if app.dragonfly_connected
                    try
                        DisconnectFromMMM();
                        app.dragonfly_connected = false;
                    catch
                        % Ignore cleanup errors for Dragonfly
                    end
                end
                
                % Clean up DAQ
                try
                    daqreset;
                catch
                    % Ignore cleanup errors for DAQ
                end
                
                fprintf('App cleanup completed\n');
                
            catch ME
                % Handle cleanup errors
                if ~contains(ME.message, 'Invalid file identifier')
                    warning('DAQ:Cleanup', 'Cleanup warning: %s', ME.message);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial values
            app.StartbuttonState = 0;
            app.StopbuttonState = 0;
            app.server = 'localhost:7111';
            app.Fs = 2500;
            app.plot_buffer_size = 2500; % Show last 2 seconds of data
            app.dragonfly_connected = false;
            
            fprintf('EMG Tab handle: %s\n', class(app.EMGTab));
            fprintf('Analog Tab handle: %s\n', class(app.AnalogTab));
            
            % Dragonfly paths
            app.VOP_baseDir = 'D:\GIT\VOP_stroke\dragonfly';
            
            % Add Dragonfly path
            if exist('D:\GIT\dragonfly\lang\matlab', 'dir')
                addpath('D:\GIT\dragonfly\lang\matlab');
            elseif exist(fullfile(app.VOP_baseDir, 'lang', 'matlab'), 'dir')
                addpath(fullfile(app.VOP_baseDir, 'lang', 'matlab'));
            else
                warning('DAQ:DragonflyPath', 'Dragonfly MATLAB path not found. Dragonfly functionality will be disabled.');
                app.EnableDragonflyCheckbox.Value = false;
                app.EnableDragonflyCheckbox.Enable = 'off';
            end
            
            if ~exist('ConnectToMMM', 'file')
                app.EnableDragonflyCheckbox.Value = false;
                app.EnableDragonflyCheckbox.Enable = 'off';
                app.EnableDragonflyCheckbox.Text = 'Dragonfly (Disabled for Testing)';
            end

            % Initialize metadata
            app.meta_data = struct;
            
            % Default settings
            app.SubID = 'swallow_JET';
            app.Filestr = '20250721';
            
            % Set save path
            initial_path = 'D:\scs_stroke\data';
            if exist(initial_path, 'dir')
                app.pathname = uigetdir(initial_path, 'Select path to save data');
            else
                app.pathname = uigetdir(pwd, 'Select path to save data');
            end
            
            if app.pathname == 0
                app.pathname = fullfile(pwd, 'daq_data');
                if ~exist(app.pathname, 'dir')
                    mkdir(app.pathname);
                end
            end
            
            % Set up paths
            app.meta_path = fullfile(app.pathname, 'metadata');
            app.fig_path = fullfile(app.pathname, 'figures');
            
            % Create directories if they don't exist
            if ~exist(app.meta_path, 'dir')
                mkdir(app.meta_path);
            end
            if ~exist(app.fig_path, 'dir')
                mkdir(app.fig_path);
            end
            
            % Set trial number
            app.TrlNum = app.getNextTrialNumber();
            
            % Set EMG channels
            app.EMG_CH = [17,27,29,23,41,43,37,47,57,51,61,63,65,75,77,71];
            
            % Set muscle labels
            app.muscle_labels = { ...
                'L_APB',...
                'NC',...
                'L-FLEX',...
                'L_FDI',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC',...
                'NC'
            };
            
            % Process muscle names
            app.muscle_names = app.get_muscle_names(app.muscle_labels);
            
            % Set analog labels
            app.analog_input_labels = {...
                'HUMAC 1 Direction', ...
                'HUMAC 2 Velocity', ...
                'HUMAC 3 Torque', ...
                'HUMAC 4 Position', ...
                'KINARM Task Start', ...
                'KINARM Recording Start', ...
                'KINARM Repetition Start', ...
                'VICON Recording start/stop//Kinarm Reach', ...
                'VICON frame capture//Kinarm Pull', ...
                'Grip Trigger', ...
                'Grip Dynomometer', ...
                'Pinch Dynomometer', ...
                'Arduino Trigger', ...
                'Arduino Amplitude', ...
                'Arduino Stim Active', ...
                'TMS Trig OUT'
            };
            
            app.analog_in_ch = 1:16;
            
            % Check EMG configuration
            if rem(length(app.EMG_CH), 2) ~= 0
                warning('EMG channels should be even number for proper plotting');
            end
            
            extra_lab = length(app.EMG_CH) - length(app.muscle_labels);
            if extra_lab ~= 0
                warning('Muscle labels (%d) not equal to EMG channel number (%d)', ...
                    length(app.muscle_labels), length(app.EMG_CH));
            end
            
            % Update interface
            app.SubjectIDField.Value = app.SubID;
            app.FileStringField.Value = app.Filestr;
            app.TrialNumberField.Value = app.TrlNum;
            app.SampleRateField.Value = app.Fs;
            app.PathLabel.Text = ['Path: ' app.pathname];
            
            % Set up metadata
            app.meta_data.sub_id = app.SubID;
            app.meta_data.date = date;
            app.meta_data.timestamp = datestr(now,'HHMMSS');
            app.meta_data.exp_name = app.Filestr;
            app.meta_data.trl_num = app.TrlNum;
            app.meta_data.fs = app.Fs;
            app.meta_data.total_num_emg_ch = length(app.muscle_labels);
            app.meta_data.emg_ch_number = app.EMG_CH;
            app.meta_data.musc_labels = app.muscle_labels;
            app.meta_data.musc_names = app.muscle_names;
            app.meta_data.analog_input_labels = app.analog_input_labels;
            app.meta_data.analog_in_ch = app.analog_in_ch;
            app.meta_data.updaterate = 0.1;
            app.meta_data.updaterate_unit = 'seconds';
            app.meta_data.stim = 0;
            app.meta_data.pulse_width = 200;
            app.meta_data.pulse_width_unit = 'micro_seconds';
            app.meta_data.duty_cycle = 100;
            app.meta_data.dutycycle_unit = 'percent';
            
                       
            % Connect to DAQ
            app.refreshDAQ();
            
            % Set up plots
            app.initializePlots();
            
            fprintf('DAQ Recording App initialized successfully.\n');
            fprintf('Save path: %s\n', app.pathname);
            beep();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.dq) || ~isvalid(app.dq)
                uialert(app.UIFigure, 'Please reconnect to DAQ first', 'DAQ Error');
                return;
            end
            
            % Get settings from interface
            app.SubID = app.SubjectIDField.Value;
            app.Filestr = app.FileStringField.Value;
            app.TrlNum = app.TrialNumberField.Value;
            app.Fs = app.SampleRateField.Value;
            
            % Update sampling rate
            if app.dq.Rate ~= app.Fs
                try
                    app.dq.Rate = app.Fs;
                    % Update Dragonfly settings
                    if app.dragonfly_connected && isfield(app, 'DF') && isfield(app.DF, 'defines')
                        app.DF.defines.SCS_EMG_FS = app.Fs;
                    end
                catch ME
                    uialert(app.UIFigure, ['Cannot change sample rate: ' ME.message], 'Sample Rate Error');
                    return;
                end
            end
            
            % Generate filename
            timestamp = datestr(now,'yyyymmdd_HHMMSS_');
            app.Filename = [timestamp, app.SubID, '_', app.Filestr, '_', sprintf('Trl%03d.bin', app.TrlNum)];
            app.metadata_filename = [timestamp, app.SubID, '_', app.Filestr, '_METADATA', sprintf('Trl%03d.mat', app.TrlNum)];
            
            app.OPfile = fullfile(app.pathname, app.Filename);
            app.OPfile_METADATA = fullfile(app.meta_path, app.metadata_filename);
            
            % Open output file
            try
                app.fid_write = fopen(app.OPfile, 'w');
                if app.fid_write == -1
                    error('Could not create output file: %s', app.OPfile);
                end
            catch ME
                uialert(app.UIFigure, ['File creation error: ' ME.message], 'File Error');
                return;
            end
            
            % Update button states
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.ReconnectButton.Enable = 'off';
            app.SelectPathButton.Enable = 'off';
            app.SubjectIDField.Enable = 'off';
            app.FileStringField.Enable = 'off';
            app.TrialNumberField.Enable = 'off';
            app.SampleRateField.Enable = 'off';
            app.EnableDragonflyCheckbox.Enable = 'off';
            
            % Update status
            app.RecordingStatusLabel.Text = 'Recording...';
            app.RecordingStatusLabel.FontColor = 'green';
            
            % Set up data callback
            app.dq.ScansAvailableFcnCount = 0.2 * app.Fs;
            app.dq.ScansAvailableFcn = @(src, evt) app.logAndBroadcastData(src, evt);
            
            % Start timer
            app.start_time = tic;
            app.timer_obj = timer('Period', 1, 'ExecutionMode', 'fixedRate', ...
                'TimerFcn', @(~,~) app.updateElapsedTime());
            start(app.timer_obj);
            
            % Send start signal
            if ~isempty(app.dqout) && isvalid(app.dqout)
                try
                    write(app.dqout, [1 0]);
                catch ME
                    warning('DAQ:DigitalOutput', 'Digital output failed: %s', ME.message);
                end
            end
            
            % Start recording
            try
                start(app.dq, 'Continuous');
                fprintf('\nRecording started: %s\n', app.Filename);
                fprintf('Output file: %s\n', app.OPfile);
            catch ME
                % Clean up on failure
                if ~isempty(app.fid_write) && app.fid_write > 0
                    fclose(app.fid_write);
                end
                if ~isempty(app.timer_obj) && isvalid(app.timer_obj)
                    stop(app.timer_obj);
                    delete(app.timer_obj);
                end
                
                % Reset interface
                app.StartButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                app.ReconnectButton.Enable = 'on';
                app.SelectPathButton.Enable = 'on';
                app.SubjectIDField.Enable = 'on';
                app.FileStringField.Enable = 'on';
                app.TrialNumberField.Enable = 'on';
                app.SampleRateField.Enable = 'on';
                app.EnableDragonflyCheckbox.Enable = 'on';
                
                app.RecordingStatusLabel.Text = 'Start Failed';
                app.RecordingStatusLabel.FontColor = 'red';
                
                uialert(app.UIFigure, ['Failed to start recording: ' ME.message], 'Start Error');
                return;
            end
            
            app.StopbuttonState = 0;
            app.StartbuttonState = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopbuttonState = 1;
            
            % Stop DAQ first
            if ~isempty(app.dq) && isvalid(app.dq) && app.dq.Running
                stop(app.dq);
                fprintf('\nDAQ stopped. Scans acquired: %d\n', app.dq.NumScansAcquired);
                
                % Wait for callbacks to complete
                pause(0.1);
            end
            
            % Send stop signal
            if ~isempty(app.dqout) && isvalid(app.dqout)
                try
                    write(app.dqout, [0 1]);
                    pause(0.001);
                catch ME
                    warning('DAQ:DigitalOutput', 'Digital output failed: %s', ME.message);
                end
            end
            
            % Stop timer
            if ~isempty(app.timer_obj) && isvalid(app.timer_obj)
                stop(app.timer_obj);
                delete(app.timer_obj);
            end
            
            % Close data file
            if ~isempty(app.fid_write) && app.fid_write > 0
                fclose(app.fid_write);
                fprintf('Data file closed: %s\n', app.OPfile);
                app.fid_write = -1; % Mark as closed
            end
            
            % Save metadata
            app.saveMetadata();
            
            % Update button states
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.ReconnectButton.Enable = 'on';
            app.SelectPathButton.Enable = 'on';
            app.SubjectIDField.Enable = 'on';
            app.FileStringField.Enable = 'on';
            app.TrialNumberField.Enable = 'on';
            app.SampleRateField.Enable = 'on';
            app.EnableDragonflyCheckbox.Enable = 'on';
            
            % Update status
            app.RecordingStatusLabel.Text = 'Stopped';
            app.RecordingStatusLabel.FontColor = 'red';
            app.ElapsedTimeLabel.Text = sprintf('Trial %d completed and saved.', app.TrlNum);
            
            % Disconnect from Dragonfly
            if app.dragonfly_connected
                try
                    DisconnectFromMMM();
                    app.dragonfly_connected = false;
                catch ME
                    warning('DAQ:DragonflyDisconnect', 'Dragonfly disconnect failed: %s', ME.message);
                end
            end
            
            % Increment trial number
            app.TrlNum = app.TrlNum + 1;
            app.TrialNumberField.Value = app.TrlNum;
            
            % Update status display
            app.DAQStatusLabel.Text = sprintf('Acquisition Terminated\nLast Trial: %d, Next Trial: %d', ...
                app.TrlNum-1, app.TrlNum);
            
            % Show data plots
            app.displaySavedData();
            
            fprintf('Trial %d completed successfully.\n', app.TrlNum-1);
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.refreshDAQ();
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            path = uigetdir(app.pathname, 'Select path to save data');
            if path ~= 0
                app.pathname = path;
                app.meta_path = fullfile(path, 'metadata');
                app.fig_path = fullfile(path, 'figures');
                
                if ~exist(app.meta_path, 'dir')
                    mkdir(app.meta_path);
                end
                if ~exist(app.fig_path, 'dir')
                    mkdir(app.fig_path);
                end
                
                app.PathLabel.Text = ['Path: ' app.pathname];
                
                app.TrlNum = app.getNextTrialNumber();
                app.TrialNumberField.Value = app.TrlNum;
                
                fprintf('Save path changed to: %s\n', app.pathname);
                fprintf('Next trial number: %d\n', app.TrlNum);
            end
        end

        % Value changed function: EnableDragonflyCheckbox
        function EnableDragonflyCheckboxValueChanged(app, event)
            if app.EnableDragonflyCheckbox.Value
                app.connectToDragonfly();
            else
                if app.dragonfly_connected
                    try
                        DisconnectFromMMM();
                        app.dragonfly_connected = false;
                        fprintf('Disconnected from Dragonfly\n');
                    catch ME
                        warning('DAQ:DragonflyDisconnect', 'Dragonfly disconnect failed: %s', ME.message);
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.cleanup();
            delete(app.UIFigure);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'System Status';
            app.DAQStatusLabel = uilabel(app.StatusPanel);
            app.DAQStatusLabel.Text = 'DAQ Status';
            app.ElapsedTimeLabel = uilabel(app.StatusPanel);
            app.ElapsedTimeLabel.Text = 'Elapsed: 0.0 s | Trial: 0';
            app.RecordingStatusLabel = uilabel(app.StatusPanel);
            app.RecordingStatusLabel.Text = 'Stopped';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Recording Controls';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.ReconnectButton = uibutton(app.ControlPanel, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.ConfigPanel = uipanel(app.UIFigure);
            app.ConfigPanel.Title = 'Configuration';
            app.SubjectIDFieldLabel = uilabel(app.ConfigPanel);
            app.SubjectIDFieldLabel.Text = 'Subject ID:';
            app.EditFieldLabel = uilabel(app.ConfigPanel);
            app.EditFieldLabel.Text = '';
            app.SubjectIDField = uieditfield(app.ConfigPanel, 'text');
            app.SubjectIDField.Value = 'swallow';
            app.FileStringFieldLabel = uilabel(app.ConfigPanel);
            app.FileStringFieldLabel.Text = 'File String:';
            app.TrialNumberField = uieditfield(app.ConfigPanel, 'numeric');
            app.TrialNumberFieldLabel = uilabel(app.ConfigPanel);
            app.TrialNumberFieldLabel.Text = 'Trial Number:';
            app.SampleRateFieldLabel = uilabel(app.ConfigPanel);
            app.SampleRateFieldLabel.Text = 'Sample Rate (Hz):';
            app.SampleRateField = uieditfield(app.ConfigPanel, 'numeric');
            app.SampleRateField.Value = 2500;
            app.SelectPathButton = uibutton(app.ConfigPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Save Path';
            app.PathLabel = uilabel(app.ConfigPanel);
            app.PathLabel.Text = 'Path: D:\scs_testing\data\test';
            app.FileStringField = uieditfield(app.ConfigPanel, 'text');
            app.FileStringField.Value = '20250721';
            app.EnableDragonflyCheckbox = uicheckbox(app.ConfigPanel);
            app.EnableDragonflyCheckbox.ValueChangedFcn = createCallbackFcn(app, @EnableDragonflyCheckboxValueChanged, true);
            app.EnableDragonflyCheckbox.Text = 'Enable Dragonfly';
            app.PlotTabs = uitabgroup(app.UIFigure);
            app.EMGTab = uitab(app.PlotTabs);
            app.EMGTab.Title = 'EMG Signals';
            app.AnalogTab = uitab(app.PlotTabs);
            app.AnalogTab.Title = 'Analog Inputs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_Recording_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end